classdef muehle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ComputerLamp          matlab.ui.control.Lamp
        ComputerLampLabel     matlab.ui.control.Label
        MenschLamp            matlab.ui.control.Lamp
        MenschLampLabel       matlab.ui.control.Label
        SPIELButton           matlab.ui.control.StateButton
        SchwarzButton         matlab.ui.control.Button
        TestButton            matlab.ui.control.Button
        WeissButton           matlab.ui.control.Button
        SpalteEditField       matlab.ui.control.NumericEditField
        SpalteEditFieldLabel  matlab.ui.control.Label
        ZeileEditField        matlab.ui.control.NumericEditField
        ZeileEditFieldLabel   matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        RandomButton          matlab.ui.control.StateButton
        Label_21              matlab.ui.control.Label
        Label_20              matlab.ui.control.Label
        Label_19              matlab.ui.control.Label
        Label_18              matlab.ui.control.Label
        Label_17              matlab.ui.control.Label
        Label_16              matlab.ui.control.Label
        Label_15              matlab.ui.control.Label
        Label_14              matlab.ui.control.Label
        Lamp_7_4              matlab.ui.control.Lamp
        Lamp_7_7              matlab.ui.control.Lamp
        Lamp_4_7              matlab.ui.control.Lamp
        Lamp_1_7              matlab.ui.control.Lamp
        Lamp_1_4              matlab.ui.control.Lamp
        Lamp_1_1              matlab.ui.control.Lamp
        Lamp_7_1              matlab.ui.control.Lamp
        Lamp_6_6              matlab.ui.control.Lamp
        Lamp_4_6              matlab.ui.control.Lamp
        Lamp_2_6              matlab.ui.control.Lamp
        Lamp_2_4              matlab.ui.control.Lamp
        Lamp_2_2              matlab.ui.control.Lamp
        Lamp_6_4              matlab.ui.control.Lamp
        Lamp_6_2              matlab.ui.control.Lamp
        Lamp_4_1              matlab.ui.control.Lamp
        Lamp_4_2              matlab.ui.control.Lamp
        Lamp_4_5              matlab.ui.control.Lamp
        Lamp_5_5              matlab.ui.control.Lamp
        Lamp_4_3              matlab.ui.control.Lamp
        Lamp_5_4              matlab.ui.control.Lamp
        Lamp_5_3              matlab.ui.control.Lamp
        Lamp_3_5              matlab.ui.control.Lamp
        Lamp_3_4              matlab.ui.control.Lamp
        Lamp_3_3              matlab.ui.control.Lamp
        Label                 matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Label_8               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        Label_13              matlab.ui.control.Label
        Label_12              matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % Spielfeld initialisieren 0 = gültig,  3 = ungültig
        spielfeld = [0 3 3 0 3 3 0;
                     3 0 3 0 3 0 3;
                     3 3 0 0 0 3 3;
                     0 0 0 3 0 0 0;
                     3 3 0 0 0 3 3;
                     3 0 3 0 3 0 3;
                     0 3 3 0 3 3 0];
        
        % Anzahl der Spielsteine
        anzWeiss = 0;
        anzSchwarz = 0;
        
        menschFertig = 0;
        
        spieLaeuft = 0;
        
    end
    
    methods (Access = private)
        
        % Funktion zur Ausgabe des Spielfeldes
        function printSpielfeld(app, spielfeld)
            
            app.spielfeld
            
            if (app.spielfeld(1,1) == 1)
                app.Lamp_1_1.Color = 'white';
            elseif (app.spielfeld(1,1) == 2)
                app.Lamp_1_1.Color = 'black';
            else 
                app.Lamp_1_1.Color = [0.80,0.80,0.80];
            end

            if (app.spielfeld(1,4) == 1)
                app.Lamp_1_4.Color = 'white';
            elseif (app.spielfeld(1,4) == 2)
                app.Lamp_1_4.Color = 'black';
            else 
                app.Lamp_1_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(1,7) == 1)
                app.Lamp_1_7.Color = 'white';
            elseif (app.spielfeld(1,7) == 2)
                app.Lamp_1_7.Color = 'black';
            else 
                app.Lamp_1_7.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(2,2) == 1)
                app.Lamp_2_2.Color = 'white';
            elseif (app.spielfeld(2,2) == 2)
                app.Lamp_2_2.Color = 'black';
            else 
                app.Lamp_2_2.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(2,4) == 1)
                app.Lamp_2_4.Color = 'white';
            elseif (app.spielfeld(2,4) == 2)
                app.Lamp_2_4.Color = 'black';
            else 
                app.Lamp_2_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(2,6) == 1)
                app.Lamp_2_6.Color = 'white';
            elseif (app.spielfeld(2,6) == 2)
                app.Lamp_2_6.Color = 'black';
            else 
                app.Lamp_2_6.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(3,3) == 1)
                app.Lamp_3_3.Color = 'white';
            elseif (app.spielfeld(3,3) == 2)
                app.Lamp_3_3.Color = 'black';
            else 
                app.Lamp_3_3.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(3,4) == 1)
                app.Lamp_3_4.Color = 'white';
            elseif (app.spielfeld(3,4) == 2)
                app.Lamp_3_4.Color = 'black';
            else 
                app.Lamp_3_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(3,5) == 1)
                app.Lamp_3_5.Color = 'white';
            elseif (app.spielfeld(3,5) == 2)
                app.Lamp_3_5.Color = 'black';
            else 
                app.Lamp_3_5.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,1) == 1)
                app.Lamp_4_1.Color = 'white';
            elseif (app.spielfeld(4,1) == 2)
                app.Lamp_4_1.Color = 'black';
            else 
                app.Lamp_4_1.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,2) == 1)
                app.Lamp_4_2.Color = 'white';
            elseif (app.spielfeld(4,2) == 2)
                app.Lamp_4_2.Color = 'black';
            else 
                app.Lamp_4_2.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,3) == 1)
                app.Lamp_4_3.Color = 'white';
            elseif (app.spielfeld(4,3) == 2)
                app.Lamp_4_3.Color = 'black';
            else 
                app.Lamp_4_3.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,5) == 1)
                app.Lamp_4_5.Color = 'white';
            elseif (app.spielfeld(4,5) == 2)
                app.Lamp_4_5.Color = 'black';
            else 
                app.Lamp_4_5.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,6) == 1)
                app.Lamp_4_6.Color = 'white';
            elseif (app.spielfeld(4,6) == 2)
                app.Lamp_4_6.Color = 'black';
            else 
                app.Lamp_4_6.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,7) == 1)
                app.Lamp_4_7.Color = 'white';
            elseif (app.spielfeld(4,7) == 2)
                app.Lamp_4_7.Color = 'black';
            else 
                app.Lamp_4_7.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(5,3) == 1)
                app.Lamp_5_3.Color = 'white';
            elseif (app.spielfeld(5,3) == 2)
                app.Lamp_5_3.Color = 'black';
            else 
                app.Lamp_5_3.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(5,4) == 1)
                app.Lamp_5_4.Color = 'white';
            elseif (app.spielfeld(5,4) == 2)
                app.Lamp_5_4.Color = 'black';
            else 
                app.Lamp_5_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(5,5) == 1)
                app.Lamp_5_5.Color = 'white';
            elseif (app.spielfeld(5,5) == 2)
                app.Lamp_5_5.Color = 'black';
            else 
                app.Lamp_5_5.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(6,2) == 1)
                app.Lamp_6_2.Color = 'white';
            elseif (app.spielfeld(6,2) == 2)
                app.Lamp_6_2.Color = 'black';
            else 
                app.Lamp_6_2.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(6,4) == 1)
                app.Lamp_6_4.Color = 'white';
            elseif (app.spielfeld(6,4) == 2)
                app.Lamp_6_4.Color = 'black';
            else 
                app.Lamp_6_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(6,6) == 1)
                app.Lamp_6_6.Color = 'white';
            elseif (app.spielfeld(6,6) == 2)
                app.Lamp_6_6.Color = 'black';
            else 
                app.Lamp_6_6.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(7,1) == 1)
                app.Lamp_7_1.Color = 'white';
            elseif (app.spielfeld(7,1) == 2)
                app.Lamp_7_1.Color = 'black';
            else 
                app.Lamp_7_1.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(7,4) == 1)
                app.Lamp_7_4.Color = 'white';
            elseif (app.spielfeld(7,4) == 2)
                app.Lamp_7_4.Color = 'black';
            else 
                app.Lamp_7_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(7,7) == 1)
                app.Lamp_7_7.Color = 'white';
            elseif (app.spielfeld(7,7) == 2)
                app.Lamp_7_7.Color = 'black';
            else 
                app.Lamp_7_7.Color = [0.80,0.80,0.80];
            end
         
        end 
        
        % Mensch am Zug
        function menschZug(app, spielfeld)
            app.menschFertig = 0;
            app.MenschLamp.Enable = 'on';
            while(app.menschFertig == 0)
                pause(1);
                s = 'Mensch ist am Zug'
            end
            app.MenschLamp.Enable = 'off';
        end
        
        % Computer am Zug
        function computerZug(app, spielfeld)
            app.ComputerLamp.Enable = 'on';
            while(1)
                zeile  = randi([1 7],1,1)
                spalte = randi([1 7],1,1)
                if(checkSetzen(app, zeile, spalte))
                    app.spielfeld(zeile, spalte) = 2;
                    app.anzSchwarz = app.anzSchwarz + 1;
                    break;
                end
            end
            pause(1);
            printSpielfeld(app, app.spielfeld);
            app.ComputerLamp.Enable = 'off';
        end
        
        % Prüft, ob ein Stein gesetzt werden kann
        % JA: gibt 1 zurück
        % NEIN: gibt 0 zurück
        function [checkSetzenOK] = checkSetzen(app, zeile, spalte)            
            if(app.spielfeld(zeile, spalte) ~= 0) || (app.anzWeiss >= 9) 
                checkSetzenOK = 0;
            else
                checkSetzenOK = 1; 
            end
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RandomButton
        function RandomButtonValueChanged(app, event)
            buttonValue = app.RandomButton.Value;   
            if(buttonValue == 1)
                app.spielfeld = randi([0 2],7,7);
            else
                app.spielfeld = zeros(7,7);      
            end
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Spielfeld zurücksetzen
            app.spielfeld = zeros(7,7);
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
           %
           % FÜR TESTS
           %
            
        end

        % Button pushed function: WeissButton
        function WeissButtonPushed(app, event)
            % Position einlesen und weißen Stein setzen
            zeile = app.ZeileEditField.Value;
            spalte = app.SpalteEditField.Value;
            if(checkSetzen(app, zeile, spalte))
                app.spielfeld(zeile, spalte) = 1;
                app.anzWeiss = app.anzWeiss + 1
                app.menschFertig = 1
            end
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: SchwarzButton
        function SchwarzButtonPushed(app, event)
            % Position einlesen und schwarzen Stein setzen
            zeile = app.ZeileEditField.Value;
            spalte = app.SpalteEditField.Value;
            if(checkSetzen(app, zeile, spalte))
                app.spielfeld(zeile, spalte) = 2;
                app.anzSchwarz = app.anzSchwarz + 1;
            end
            printSpielfeld(app, app.spielfeld);
        end

        % Value changed function: SPIELButton
        function SPIELButtonValueChanged(app, event)
            value = app.SPIELButton.Value;
            if(value == 1)
                app.SchwarzButton.Visible = 'off'; 
                while(value)
                    menschZug(app, app.spielfeld);
                    computerZug(app, app.spielfeld);
                end 
            else
                app.menschFertig = 1;
                app.SchwarzButton.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '___________________________________';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '|';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '|';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '|';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '|';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '_____________________';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '___________________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '_____________________';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '___________________';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '___________________________________';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '___________________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '______________________________________________________';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '______________________________________________________';
            app.Lamp_3_3 = uilamp(app.UIFigure);
            app.Lamp_3_4 = uilamp(app.UIFigure);
            app.Lamp_3_5 = uilamp(app.UIFigure);
            app.Lamp_5_3 = uilamp(app.UIFigure);
            app.Lamp_5_4 = uilamp(app.UIFigure);
            app.Lamp_4_3 = uilamp(app.UIFigure);
            app.Lamp_5_5 = uilamp(app.UIFigure);
            app.Lamp_4_5 = uilamp(app.UIFigure);
            app.Lamp_4_2 = uilamp(app.UIFigure);
            app.Lamp_4_1 = uilamp(app.UIFigure);
            app.Lamp_6_2 = uilamp(app.UIFigure);
            app.Lamp_6_4 = uilamp(app.UIFigure);
            app.Lamp_2_2 = uilamp(app.UIFigure);
            app.Lamp_2_4 = uilamp(app.UIFigure);
            app.Lamp_2_6 = uilamp(app.UIFigure);
            app.Lamp_4_6 = uilamp(app.UIFigure);
            app.Lamp_6_6 = uilamp(app.UIFigure);
            app.Lamp_7_1 = uilamp(app.UIFigure);
            app.Lamp_1_1 = uilamp(app.UIFigure);
            app.Lamp_1_4 = uilamp(app.UIFigure);
            app.Lamp_1_7 = uilamp(app.UIFigure);
            app.Lamp_4_7 = uilamp(app.UIFigure);
            app.Lamp_7_7 = uilamp(app.UIFigure);
            app.Lamp_7_4 = uilamp(app.UIFigure);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '1                  2                3                4              5              6                 7';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '1';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '2';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '3';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '4';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '5';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '6';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '7';
            app.RandomButton = uibutton(app.UIFigure, 'state');
            app.RandomButton.ValueChangedFcn = createCallbackFcn(app, @RandomButtonValueChanged, true);
            app.RandomButton.Text = 'Random';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ZeileEditFieldLabel = uilabel(app.UIFigure);
            app.ZeileEditFieldLabel.Text = 'Zeile';
            app.ZeileEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpalteEditFieldLabel = uilabel(app.UIFigure);
            app.SpalteEditFieldLabel.Text = 'Spalte';
            app.SpalteEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeissButton = uibutton(app.UIFigure, 'push');
            app.WeissButton.ButtonPushedFcn = createCallbackFcn(app, @WeissButtonPushed, true);
            app.WeissButton.Text = 'Weiss';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.SchwarzButton = uibutton(app.UIFigure, 'push');
            app.SchwarzButton.ButtonPushedFcn = createCallbackFcn(app, @SchwarzButtonPushed, true);
            app.SchwarzButton.Text = 'Schwarz';
            app.SPIELButton = uibutton(app.UIFigure, 'state');
            app.SPIELButton.ValueChangedFcn = createCallbackFcn(app, @SPIELButtonValueChanged, true);
            app.SPIELButton.Text = 'SPIEL';
            app.MenschLampLabel = uilabel(app.UIFigure);
            app.MenschLampLabel.Text = 'Mensch';
            app.MenschLamp = uilamp(app.UIFigure);
            app.MenschLamp.Enable = 'off';
            app.ComputerLampLabel = uilabel(app.UIFigure);
            app.ComputerLampLabel.Text = 'Computer';
            app.ComputerLamp = uilamp(app.UIFigure);
            app.ComputerLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = muehle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end