classdef muehle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SpielaktivLamp                  matlab.ui.control.Lamp
        SpielaktivLampLabel             matlab.ui.control.Label
        GewinnerLamp                    matlab.ui.control.Lamp
        GewinnerLampLabel               matlab.ui.control.Label
        KorrekturenAbgeschlossenButton  matlab.ui.control.Button
        ComputerfertigButton            matlab.ui.control.Button
        MenschfertigButton              matlab.ui.control.Button
        nehmenButton                    matlab.ui.control.Button
        setzenButton                    matlab.ui.control.Button
        lschenButton                    matlab.ui.control.Button
        DisconnectEV3Button             matlab.ui.control.Button
        ConnectEV3Button                matlab.ui.control.Button
        EinlesenButton                  matlab.ui.control.Button
        ComputerLamp                    matlab.ui.control.Lamp
        ComputerLampLabel               matlab.ui.control.Label
        MenschLamp                      matlab.ui.control.Lamp
        MenschLampLabel                 matlab.ui.control.Label
        SPIELButton                     matlab.ui.control.StateButton
        SchwarzButton                   matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        WeissButton                     matlab.ui.control.Button
        SpalteEditField                 matlab.ui.control.NumericEditField
        SpalteEditFieldLabel            matlab.ui.control.Label
        ZeileEditField                  matlab.ui.control.NumericEditField
        ZeileEditFieldLabel             matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        RandomButton                    matlab.ui.control.StateButton
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Lamp_7_4                        matlab.ui.control.Lamp
        Lamp_7_7                        matlab.ui.control.Lamp
        Lamp_4_7                        matlab.ui.control.Lamp
        Lamp_1_7                        matlab.ui.control.Lamp
        Lamp_1_4                        matlab.ui.control.Lamp
        Lamp_1_1                        matlab.ui.control.Lamp
        Lamp_7_1                        matlab.ui.control.Lamp
        Lamp_6_6                        matlab.ui.control.Lamp
        Lamp_4_6                        matlab.ui.control.Lamp
        Lamp_2_6                        matlab.ui.control.Lamp
        Lamp_2_4                        matlab.ui.control.Lamp
        Lamp_2_2                        matlab.ui.control.Lamp
        Lamp_6_4                        matlab.ui.control.Lamp
        Lamp_6_2                        matlab.ui.control.Lamp
        Lamp_4_1                        matlab.ui.control.Lamp
        Lamp_4_2                        matlab.ui.control.Lamp
        Lamp_4_5                        matlab.ui.control.Lamp
        Lamp_5_5                        matlab.ui.control.Lamp
        Lamp_4_3                        matlab.ui.control.Lamp
        Lamp_5_4                        matlab.ui.control.Lamp
        Lamp_5_3                        matlab.ui.control.Lamp
        Lamp_3_5                        matlab.ui.control.Lamp
        Lamp_3_4                        matlab.ui.control.Lamp
        Lamp_3_3                        matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % Spielfeld initialisieren 0 = gültig,  3 = ungültig, 4 = Rand
        spielfeld = [0 3 3 0 3 3 0;
                     3 0 3 0 3 0 3;
                     3 3 0 0 0 3 3;
                     0 0 0 4 0 0 0;
                     3 3 0 0 0 3 3;
                     3 0 3 0 3 0 3;
                     0 3 3 0 3 3 0];
        
        % Anzahl der Spielsteine
        anzWeiss = 0;
        anzStartWeiss = 0;
        
        anzSchwarz = 0;
        anzStartSchwarz = 0;
        
        menschFertig = 0;
        computerFertig = 0;
        
        korrekturenAbgeschlossen = 0;
        
%       brickObj = EV3();
        
        spieLaeuft = 0;
        
        gewinner = 0;
        
        % Mühle Positionen:
        % 0 für nicht gebaut.
        muehleList = zeros(1, 16);
        
        menschZugfertig = 0;
        computerZugfertig = 0;
        
    end
    
    methods (Access = private)
        
        % Funktion zur Ausgabe des Spielfeldes
        function printSpielfeld(app, spielfeld)
            
            app.spielfeld
            
            if (app.spielfeld(1,1) == 1)
                app.Lamp_1_1.Color = 'white';
            elseif (app.spielfeld(1,1) == 2)
                app.Lamp_1_1.Color = 'black';
            else 
                app.Lamp_1_1.Color = [0.80,0.80,0.80];
            end

            if (app.spielfeld(1,4) == 1)
                app.Lamp_1_4.Color = 'white';
            elseif (app.spielfeld(1,4) == 2)
                app.Lamp_1_4.Color = 'black';
            else 
                app.Lamp_1_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(1,7) == 1)
                app.Lamp_1_7.Color = 'white';
            elseif (app.spielfeld(1,7) == 2)
                app.Lamp_1_7.Color = 'black';
            else 
                app.Lamp_1_7.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(2,2) == 1)
                app.Lamp_2_2.Color = 'white';
            elseif (app.spielfeld(2,2) == 2)
                app.Lamp_2_2.Color = 'black';
            else 
                app.Lamp_2_2.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(2,4) == 1)
                app.Lamp_2_4.Color = 'white';
            elseif (app.spielfeld(2,4) == 2)
                app.Lamp_2_4.Color = 'black';
            else 
                app.Lamp_2_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(2,6) == 1)
                app.Lamp_2_6.Color = 'white';
            elseif (app.spielfeld(2,6) == 2)
                app.Lamp_2_6.Color = 'black';
            else 
                app.Lamp_2_6.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(3,3) == 1)
                app.Lamp_3_3.Color = 'white';
            elseif (app.spielfeld(3,3) == 2)
                app.Lamp_3_3.Color = 'black';
            else 
                app.Lamp_3_3.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(3,4) == 1)
                app.Lamp_3_4.Color = 'white';
            elseif (app.spielfeld(3,4) == 2)
                app.Lamp_3_4.Color = 'black';
            else 
                app.Lamp_3_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(3,5) == 1)
                app.Lamp_3_5.Color = 'white';
            elseif (app.spielfeld(3,5) == 2)
                app.Lamp_3_5.Color = 'black';
            else 
                app.Lamp_3_5.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,1) == 1)
                app.Lamp_4_1.Color = 'white';
            elseif (app.spielfeld(4,1) == 2)
                app.Lamp_4_1.Color = 'black';
            else 
                app.Lamp_4_1.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,2) == 1)
                app.Lamp_4_2.Color = 'white';
            elseif (app.spielfeld(4,2) == 2)
                app.Lamp_4_2.Color = 'black';
            else 
                app.Lamp_4_2.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,3) == 1)
                app.Lamp_4_3.Color = 'white';
            elseif (app.spielfeld(4,3) == 2)
                app.Lamp_4_3.Color = 'black';
            else 
                app.Lamp_4_3.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,5) == 1)
                app.Lamp_4_5.Color = 'white';
            elseif (app.spielfeld(4,5) == 2)
                app.Lamp_4_5.Color = 'black';
            else 
                app.Lamp_4_5.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,6) == 1)
                app.Lamp_4_6.Color = 'white';
            elseif (app.spielfeld(4,6) == 2)
                app.Lamp_4_6.Color = 'black';
            else 
                app.Lamp_4_6.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(4,7) == 1)
                app.Lamp_4_7.Color = 'white';
            elseif (app.spielfeld(4,7) == 2)
                app.Lamp_4_7.Color = 'black';
            else 
                app.Lamp_4_7.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(5,3) == 1)
                app.Lamp_5_3.Color = 'white';
            elseif (app.spielfeld(5,3) == 2)
                app.Lamp_5_3.Color = 'black';
            else 
                app.Lamp_5_3.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(5,4) == 1)
                app.Lamp_5_4.Color = 'white';
            elseif (app.spielfeld(5,4) == 2)
                app.Lamp_5_4.Color = 'black';
            else 
                app.Lamp_5_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(5,5) == 1)
                app.Lamp_5_5.Color = 'white';
            elseif (app.spielfeld(5,5) == 2)
                app.Lamp_5_5.Color = 'black';
            else 
                app.Lamp_5_5.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(6,2) == 1)
                app.Lamp_6_2.Color = 'white';
            elseif (app.spielfeld(6,2) == 2)
                app.Lamp_6_2.Color = 'black';
            else 
                app.Lamp_6_2.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(6,4) == 1)
                app.Lamp_6_4.Color = 'white';
            elseif (app.spielfeld(6,4) == 2)
                app.Lamp_6_4.Color = 'black';
            else 
                app.Lamp_6_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(6,6) == 1)
                app.Lamp_6_6.Color = 'white';
            elseif (app.spielfeld(6,6) == 2)
                app.Lamp_6_6.Color = 'black';
            else 
                app.Lamp_6_6.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(7,1) == 1)
                app.Lamp_7_1.Color = 'white';
            elseif (app.spielfeld(7,1) == 2)
                app.Lamp_7_1.Color = 'black';
            else 
                app.Lamp_7_1.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(7,4) == 1)
                app.Lamp_7_4.Color = 'white';
            elseif (app.spielfeld(7,4) == 2)
                app.Lamp_7_4.Color = 'black';
            else 
                app.Lamp_7_4.Color = [0.80,0.80,0.80];
            end
            
            if (app.spielfeld(7,7) == 1)
                app.Lamp_7_7.Color = 'white';
            elseif (app.spielfeld(7,7) == 2)
                app.Lamp_7_7.Color = 'black';
            else 
                app.Lamp_7_7.Color = [0.80,0.80,0.80];
            end
         
        end 
        
        %
        %
        % Mensch am Zug
        %
        %
        function menschZug(app, spielfeld)
            app.menschFertig = 0;
            app.MenschLamp.Enable = 'on';
            
            s = 'Mensch ist am Zug'
            % Mensch legt einen Stein auf das Spielfeld
            while(app.menschFertig == 0)
                pause(1);
            end
            
            % Spielfeld wird vom Roboter eingelesen
%            
%           app.spielfeld = einlesen(app.brickObj);
%
            printSpielfeld(app, app.spielfeld);
            
            % Möglichkeit, falsch eingelesene Steine manuell zu
            % korrigieren, bevor der Computer wieder dran ist
            s = 'Korrekturen mauell eingeben! Danach "Korrekturen Abegschlossen" drücken.'
            while(app.korrekturenAbgeschlossen == 0)
                pause(1);
            end 
            s = 'Mensch ist fertig'
            
            % zähle Anzahl der weißen und schwarzen Steine, wichtig, da der
            % Mensch eine Mühle gebaut haben könnte und daher einen weißen
            % Stein geklaut hätte.
            % weiß
            anz = 0;
            for i = 1:7
                for j = 1:7
                    if(app.spielfeld(i,j) == 1)
                        anz = anz + 1;
                    end
                end
            end
            app.anzWeiss = anz;
            % schwarz
            anz = 0;
            for i = 1:7
                for j = 1:7
                    if(app.spielfeld(i,j) == 2)
                        anz = anz + 1;
                    end
                end
            end
            app.anzSchwarz = anz;
            
            s = 'Anzahl weisser und schwarzer Steine:'
            disp(app.anzWeiss)
            disp(app.anzSchwarz)
            
            app.MenschLamp.Enable = 'off';
            app.menschFertig = 0;
            app.korrekturenAbgeschlossen = 0;
        end
        
        %
        %
        % Computer am Zug
        %
        %
        function computerZug(app, spielfeld)
            s = 'Computer ist am Zug'
            
            % Spielfeld-Matrix erweitern, um besser arbeiten zu können
            tempspielfeld = [4 4 4 4 4 4 4 4 4
                             4 0 3 3 0 3 3 0 4;
                             4 3 0 3 0 3 0 3 4;
                             4 3 3 0 0 0 3 3 4;
                             4 0 0 0 4 0 0 0 4;
                             4 3 3 0 0 0 3 3 4;
                             4 3 0 3 0 3 0 3 4;
                             4 0 3 3 0 3 3 0 4;
                             4 4 4 4 4 4 4 4 4;];
            for i = 2:8
                for j = 2:8
                    tempspielfeld(i,j) = app.spielfeld(i-1,j-1);
                end
            end
            
            % Ausgabe im Command Window
            %tempspielfeld
            
            app.ComputerLamp.Enable = 'on';
            app.computerFertig = 0;
            
            % Eröffnung: 9 Steine setzen
            if(app.anzStartWeiss <9)
                
                % Erster Zug:
                if(app.anzWeiss == 0)
                    
                    s = 'Erster Zug'
                    
                    while(1)
                    zeile = randi([1,7],1,1);
                    spalte = randi([1,7],1,1);
                    
                        % Ist die Position frei? Wenn ja, dort setzen
                        if(checkSetzen(app, zeile, spalte))
                            app.spielfeld(zeile, spalte) = 1;
                            break;
                            
                        % Wenn nicht, generiere Zufallszahlen, bis eine
                        % mögliche Stelle gefunden wurde
                        else
                            continue;
                        end
                    end
                    
                    % Couter erhöhen
                    app.anzWeiss = app.anzWeiss +1;
                    app.anzStartWeiss = app.anzStartWeiss +1;
                
                else

                    % 2. - 9. Zug:
                    % Spielsteine möglichst benachbart setzen, um zufällig eine
                    % Mühle zu erlangen:
                    
                    s = '2.-9. Zug'
                    disp(app.anzStartWeiss+1)
                    
                    zeileWeisserStein = 0;
                    spalteWeisserStein = 0;
                    
                    % Status Variable
                    erfolg = 0;

                    while(1) 
                        
                        % Weißen Stein finden
                        for i = 1:7
                            for j = 1:7
                                if(app.spielfeld(i,j) == 1)
                                    zeileWeisserStein = i
                                    spalteWeisserStein = j
                                
                                    % Prüfe nun, ob eine benachbarte Position frei ist. Wenn ja,
                                    % setze dorthin, ansonsten prüfe die
                                    % anderen Nachbar-Stellen
                                    % Nutze dazu tempspielfeld:
                                    for a = 2:8
                                        for b = 2:8
                                            tempspielfeld(a,b) = app.spielfeld(a-1,b-1);
                                        end
                                    end
                                    
                                    zeileNeu = 0;
                                    spalteNeu = 0;
                                    zeileWeisserStein = zeileWeisserStein +1;
                                    spalteWeisserStein = spalteWeisserStein +1;
                                    
                                    for m = 1:3
                                        if(tempspielfeld(zeileWeisserStein-m, spalteWeisserStein) == 4) || (tempspielfeld(zeileWeisserStein-m, spalteWeisserStein) == 2)
                                            break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                        end
                                        if(tempspielfeld(zeileWeisserStein-m, spalteWeisserStein) == 0)
                                        zeileNeu = zeileWeisserStein-m;
                                        spalteNeu = spalteWeisserStein;
                                        break;
                                        end
                                    end
                                    
                                    for n = 1:3
                                        if(tempspielfeld(zeileWeisserStein, spalteWeisserStein+n) == 4) || (tempspielfeld(zeileWeisserStein, spalteWeisserStein+n) == 2)
                                            break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                        end
                                        if(tempspielfeld(zeileWeisserStein, spalteWeisserStein+n) == 0)
                                            zeileNeu = zeileWeisserStein;
                                            spalteNeu = spalteWeisserStein+n;
                                            break;
                                        end
                                    end
                                    
                                    for m = 1:3
                                        if(tempspielfeld(zeileWeisserStein+m, spalteWeisserStein) == 4) || (tempspielfeld(zeileWeisserStein+m, spalteWeisserStein) == 2)
                                            break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                        end
                                        if(tempspielfeld(zeileWeisserStein+m, spalteWeisserStein) == 0)
                                            zeileNeu = zeileWeisserStein+m;
                                            spalteNeu = spalteWeisserStein;
                                            break;
                                            end
                                    end
                                    
                                    for n = 1:3
                                        if(tempspielfeld(zeileWeisserStein, spalteWeisserStein-n) == 4)  || (tempspielfeld(zeileWeisserStein, spalteWeisserStein-n) == 2)
                                            break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                        end
                                        if(tempspielfeld(zeileWeisserStein, spalteWeisserStein-n) == 0)
                                            zeileNeu = zeileWeisserStein;
                                            spalteNeu = spalteWeisserStein-n;
                                            break;
                                        end
                                    end
                                    
                                    if(zeileNeu ~= 0) && (spalteNeu ~= 0)
                                        % Nachbarstelle ist gefunden => Neue Position steht
                                        % fest
                                        
                                        % Zurück zur einfachen Matrix
                                        zeileNeu = zeileNeu -1 ;
                                        spalteNeu = spalteNeu -1;
                                        
                                        s = 'Nachbarstelle gefunden: Zeile, Spalte'
                                        disp(zeileNeu)
                                        disp(spalteNeu)
                                        
                                        % Spielstein setzen
                                        app.spielfeld(zeileNeu, spalteNeu) = 1;
                                        
                                        % Stein Setzen
%                                       zeile = zeileNeu;
%                                       spalte = spalteNeu;
%                                       aufheben(app.brickObj);
%                                       fahreZu(app.brickObj, zeile, spalte, 1);
%                                       ablegen(app.brickObj);
%                                       printSpielfeld(app, app.spielfeld);
%                                       fahreZu(app.brickObj, zeile, spalte, -1);
%                                       armRunter(app.brickObj);

                                        % Couter erhöhen
                                        app.anzWeiss = app.anzWeiss +1;
                                        app.anzStartWeiss = app.anzStartWeiss +1;
                                        
                                        % Fertig. Weißer Stein ist erfolgreich
                                        % neben einen anderen weißen Stein gesetzt.
                                        
                                        % While Schleife wieder verlassen!
                                        erfolg = 1;
                                        
                                        % zuerst aberinnere for-Schleife
                                        % verlassen
                                        break; 
                                    end 
                                end
                            end
                            % Wenn erfolgreich gesetzt, auch äußere
                            % for-Schleife verlassen!
                            if(erfolg == 1)
                                break;
                            end
                        end 
                        
                        % Hier wird die While-Schleife verlassen, da der
                        % Stein erfolgreich gesetzt wurde!
                        if(erfolg == 1)
                            break;
                        end
                        % Wenn keine freie Nachbar-Stelle gefunden wurde,
                        % wird der weiße Stein zufällig gesetzt.
                        
                        s = 'Keine freie Nachbarstelle gefunden!'
                        while(1)
                        zeile = randi([1,7],1,1);
                        spalte = randi([1,7],1,1);
                        
                            % Ist die Position frei? Wenn ja, dort setzen
                            if(checkSetzen(app, zeile, spalte))
                                app.spielfeld(zeile, spalte) = 1;
                                
                                % Stein Setzen
%                               aufheben(app.brickObj);
%                               fahreZu(app.brickObj, zeile, spalte, 1);
%                               ablegen(app.brickObj);
%                               printSpielfeld(app, app.spielfeld);
%                               fahreZu(app.brickObj, zeile, spalte, -1);
%                               armRunter(app.brickObj);
                                break;
                                
                            % Wenn nicht, generiere Zufallszahlen, bis eine
                            % mögliche Stelle gefunden wurde
                            else
                                continue;
                            end
                        end
                        
                        % Couter erhöhen
                        app.anzWeiss = app.anzWeiss +1;
                        app.anzStartWeiss = app.anzStartWeiss +1;
                                        
                        
                        % Fertig. Weißer Stein ist erfolgreich gesetzt.
                        
                        % While Schleife wieder verlassen!
                        break;
                    end
                end
                
            %    
            %    
            % Normales Spiel läuft, bis ein Spieler nur noch 3
            % Spielsteine auf dem Feld hat
            % 
            % HINWEIS: In unserer Version ist das Spiel gewonnen, wenn der
            % Gegner nur noch 3 Steine hat. Es gibt also kein Springen!
            %
            %
            
            elseif(app.anzStartWeiss == 9) && (app.anzWeiss > 3) && (app.anzSchwarz > 3)
                
                s = 'Alle Steine gesetzt, normal spielen!'
                
                % Status Variable
                erfolg = 0;
                
                while(1)
                    
                    % Weißen Stein wählen, der bewegt werden kann
                    for i = 1:7
                        for j = 1:7
                            if(app.spielfeld(i,j) == 1)
                                zeileWeisserStein = i
                                spalteWeisserStein = j
                            
                                % Prüfe nun, ob eine benachbarte Position frei ist. Wenn ja,
                                % setze dorthin, ansonsten prüfe die
                                % anderen Nachbar-Stellen
                                % Nutze dazu tempspielfeld:
                                for a = 2:8
                                    for b = 2:8
                                        tempspielfeld(a,b) = app.spielfeld(a-1,b-1);
                                    end
                                end
                                
                                zeileNeu = 0;
                                spalteNeu = 0;
                                
                                % Alte Position Speichern
                                zeileWeisserStein = zeileWeisserStein +1;
                                spalteWeisserStein = spalteWeisserStein +1;
                                
                                for m = 1:3
                                    if(tempspielfeld(zeileWeisserStein-m, spalteWeisserStein) == 4) || (tempspielfeld(zeileWeisserStein-m, spalteWeisserStein) == 2)
                                        break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                    elseif(tempspielfeld(zeileWeisserStein-m, spalteWeisserStein) == 0)
                                        zeileNeu = zeileWeisserStein-m;
                                        spalteNeu = spalteWeisserStein;
                                        s = 'Oben ist frei'
                                        break;
                                    end
                                end
                                
                                for n = 1:3
                                    if(tempspielfeld(zeileWeisserStein, spalteWeisserStein+n) == 4) || (tempspielfeld(zeileWeisserStein, spalteWeisserStein+n) == 2)
                                        break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                    elseif(tempspielfeld(zeileWeisserStein, spalteWeisserStein+n) == 0)
                                        zeileNeu = zeileWeisserStein;
                                        spalteNeu = spalteWeisserStein+n;
                                        s = 'Rechts ist frei'
                                        break;
                                    end
                                end
                                
                                for m = 1:3
                                    if(tempspielfeld(zeileWeisserStein+m, spalteWeisserStein) == 4) || (tempspielfeld(zeileWeisserStein+m, spalteWeisserStein) == 2)
                                        break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                    elseif(tempspielfeld(zeileWeisserStein+m, spalteWeisserStein) == 0)
                                        zeileNeu = zeileWeisserStein+m;
                                        spalteNeu = spalteWeisserStein;
                                        s = 'Unten ist frei'
                                        break;
                                    end
                                end
                                
                                for n = 1:3
                                    if(tempspielfeld(zeileWeisserStein, spalteWeisserStein-n) == 4)  || (tempspielfeld(zeileWeisserStein, spalteWeisserStein-n) == 2)
                                        break; % über den Rand gelaufen oder gegnerischer Stein im Weg
                                    elseif(tempspielfeld(zeileWeisserStein, spalteWeisserStein-n) == 0)
                                        zeileNeu = zeileWeisserStein;
                                        spalteNeu = spalteWeisserStein-n;
                                        s = 'Links ist frei'
                                        break;
                                    end
                                end
                                
                                if(zeileNeu ~= 0) && (spalteNeu ~= 0)
                                    % Nachbarstelle ist gefunden und Neue Position steht
                                    % fest
                                    
                                    % Zurück zur einfachen Matrix
                                    zeileNeu = zeileNeu -1 ;
                                    spalteNeu = spalteNeu -1;
                                    
                                    zeileWeisserStein = zeileWeisserStein -1;
                                    spalteWeisserStein = spalteWeisserStein -1;
                                    
                                    s = 'Nachbarstelle gefunden: Alte Pos: Zeile, Spalte; Neue  Pos: Zeile, Spalte;'
                                    disp(zeileWeisserStein)
                                    disp(spalteWeisserStein)
                                    disp(zeileNeu)
                                    disp(spalteNeu)
                                    
                                    % Spielstein wegnehmen
                                    app.spielfeld(zeileWeisserStein, spalteWeisserStein) = 0;
                                    
                                    % Stein Nehmen
%                                   zeile = zeileWeisserStein;
%                                   spalte = spalteWeisserStein;
%                                   armHoch(app.brickObj);
%                                   fahreZu(app.brickObj, zeile, spalte, 1)
%                                   armRunter(app.brickObj);
%                                   aufheben(app.brickObj);
%                                   printSpielfeld(app, app.spielfeld);
%                                   fahreZu(app.brickObj, zeile, spalte, -1);
%                                   ablegen(app.brickObj);
%                                   armRunter(app.brickObj);

                                    
                                    % Weißer Stein weggenommen.
                                    
                                    pause(0.5);
                                    
                                    % Weißen Stein an neue Position legen
                                    app.spielfeld(zeileNeu, spalteNeu) = 1;
                                    
                                    % Stein Setzen
%                                   aufheben(app.brickObj);
%                                   fahreZu(app.brickObj, zeile, spalte, 1);
%                                   ablegen(app.brickObj);
%                                   printSpielfeld(app, app.spielfeld);
%                                   fahreZu(app.brickObj, zeile, spalte, -1);
%                                   armRunter(app.brickObj);


                                    % While Schleife wieder verlassen!
                                    erfolg = 1
                                    
                                    % zuerst aberinnere for-Schleife
                                    % verlassen
                                    break; 
                                end 
                            end
                        end
                        % Wenn erfolgreich gesetzt, auch äußere
                        % for-Schleife verlassen!
                        if(erfolg == 1)
                            break;
                        end
                    end 
                    
                    % Hier wird die While-Schleife verlassen, da der
                    % Stein erfolgreich versetzt wurde!
                    if(erfolg == 1)
                        break;
                    end
                    
                    % Wenn keine freie Nachbar-Stelle gefunden wurde,
                    % hat der Mensch das Spiel gewonnen!
                    
                    s = 'Keine freie Nachbarstelle gefunden!'
                    s = 'Der Mensch hat gewonnen!'
                    app.gewinner = 2;
                    break;
                end

  
            else
                % Gewinner steht fest
                if(app.anzWeiss <= 3)
                    app.gewinner = 2;
                    s = 'Spiel ist zu Ende, Weiss hat nur noch 3 Steine.'
                end
                if(app.anzSchwarz <= 3)
                    app.gewinner = 1;
                    s = 'Spiel ist zu Ende, Schwarz hat nur noch als 3 Steine.'
                end
            end
            
            
            % Finde alle weißen Mühlen
            % Wenn eine geschlossen wurde, nimmt der Computer einen
            % schwarzen Stein weg. Diesen darf er nicht aus einer Mühle
            % nehmen!
            
            mueh = zeros(1,16);
            
            % Mühlen suchen:
            % f ist Variable für Farbe 1 = weiss, 2 = schwarz
            for f = 1:2
                f
                if(app.spielfeld(1,1) == f) && (app.spielfeld(1,4) == f) && (app.spielfeld(1,7) == f)
                    mueh(1) = f;
                end
                if(app.spielfeld(2,2) == f) && (app.spielfeld(2,4) == f) && (app.spielfeld(2,6) == f)
                    mueh(2) = f;
                end
                if(app.spielfeld(3,3) == f) && (app.spielfeld(3,4) == f) && (app.spielfeld(3,5) == f)
                    mueh(3) = f;
                end
                if(app.spielfeld(5,3) == f) && (app.spielfeld(5,4) == f) && (app.spielfeld(5,5) == f)
                    mueh(4) = f;
                end
                if(app.spielfeld(6,2) == f) && (app.spielfeld(6,4) == f) && (app.spielfeld(6,6) == f)
                    mueh(5) = f;;
                end
                if(app.spielfeld(7,1) == f) && (app.spielfeld(7,4) == f) && (app.spielfeld(7,7) == f)
                    mueh(6) = f;
                end
                if(app.spielfeld(1,1) == f) && (app.spielfeld(4,1) == f) && (app.spielfeld(7,1) == f)
                    mueh(7) = f;
                end
                if(app.spielfeld(2,2) == f) && (app.spielfeld(4,2) == f) && (app.spielfeld(6,2) == f)
                    mueh(8) = f;
                end
                if(app.spielfeld(3,3) == f) && (app.spielfeld(4,3) == f) && (app.spielfeld(5,3) == f)
                    mueh(9) = f;
                end
                if(app.spielfeld(3,5) == f) && (app.spielfeld(4,5) == f) && (app.spielfeld(5,5) == f)
                    mueh(10) = f;
                end
                if(app.spielfeld(2,6) == f) && (app.spielfeld(4,6) == f) && (app.spielfeld(6,6) == f)
                    mueh(11) = f;
                end
                if(app.spielfeld(1,7) == f) && (app.spielfeld(4,7) == f) && (app.spielfeld(7,7) == f)
                    mueh(12) = f;
                end
                if(app.spielfeld(1,4) == f) && (app.spielfeld(2,4) == f) && (app.spielfeld(3,4) == f)
                    mueh(13) = f;
                end
                if(app.spielfeld(4,1) == f) && (app.spielfeld(4,2) == f) && (app.spielfeld(4,3) == f)
                    mueh(14) = f;
                end
                if(app.spielfeld(5,4) == f) && (app.spielfeld(6,4) == f) && (app.spielfeld(7,4) == f)
                    mueh(15) = f;
                end
                if(app.spielfeld(4,5) == f) && (app.spielfeld(4,6) == f) && (app.spielfeld(4,7) == f)
                    mueh(16) = f;
                end
            end
            
            mueh
            
            % Prüfe, ob der Computer gerade eine Mühle geschlossen hat
            
            geklaut = 0;
            
            for p = 1:16
                % Ja
                if (app.muehleList(p) == 0) && (mueh(p) == 1)
                    % Klaue einen Schwarzen Stein
                    % zuerst: suche einen schwarzen Stein
                    while(1)
                        zeileKlauen = randi([1,7],1,1)
                        spalteKlauen = randi([1,7],1,1)
                        if(app.spielfeld(zeileKlauen, spalteKlauen) == 2);
                            s = 'Muehle Nummer geschlossen:'
                            disp(p)
                            % Prüfe, ob der schwarze selbst in einer Mühle
                            % liegt:
                            %
                            % Noch zu lösen
                            %
                            app.spielfeld(zeileKlauen, spalteKlauen) = 0;
                            
                            s = 'Schwarzen Stein von Zeile, Spalte geklaut'
                            disp(zeileKlauen)
                            disp(spalteKlauen)
                            
                            % Mühle fest eintragen.
                            app.muehleList(p) = 1;
                            app.muehleList
                            geklaut = 1;
                            break;
                        else
                            continue;
                        end
                    end    
                end
                if (geklaut == 1)
                    break;
                end
            end
            
            % Mühlen in muehleList aktualisieren
            for t = 1:16
                app.muehleList(t) = mueh(t);
            end

            % zähle Anzahl der weißen und schwarzen Steine
            % weiß
            anz = 0;
            for i = 1:7
                for j = 1:7
                    if(app.spielfeld(i,j) == 1)
                        anz = anz + 1;
                    end
                end
            end
            app.anzWeiss = anz;
            % schwarz
            anz = 0;
            for i = 1:7
                for j = 1:7
                    if(app.spielfeld(i,j) == 2)
                        anz = anz + 1;
                    end
                end
            end
            app.anzSchwarz = anz;
            
            s = 'Anzahl Weisser und schwarzer Steine:'
            disp(app.anzWeiss)
            disp(app.anzSchwarz)

            
            printSpielfeld(app, app.spielfeld);
            pause(1);
            app.ComputerLamp.Enable = 'off';
        end
        
        %
        %
        % ======= Computer am Zug Ende ==========
        %
        %
        
        % Prüft, ob ein Stein gesetzt werden kann
        % JA: gibt 1 zurück
        % NEIN: gibt 0 zurück
        function [checkSetzenOK] = checkSetzen(app, zeile, spalte)            
            if(app.spielfeld(zeile, spalte) ~= 0) || (app.anzSchwarz >= 9) 
                checkSetzenOK = 0;
            else
                checkSetzenOK = 1; 
            end
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RandomButton
        function RandomButtonValueChanged(app, event)
            buttonValue = app.RandomButton.Value;   
            if(buttonValue == 1)
                app.spielfeld = randi([0 2],7,7);
            else
                app.spielfeld = zeros(7,7);      
            end
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Spielfeld zurücksetzen
            app.spielfeld = zeros(7,7);
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
           %
           % FÜR TESTS
           %
            
        end

        % Button pushed function: WeissButton
        function WeissButtonPushed(app, event)
            % Position einlesen und weißen Stein setzen
            zeile = app.ZeileEditField.Value;
            spalte = app.SpalteEditField.Value;
            if(checkSetzen(app, zeile, spalte))
                app.spielfeld(zeile, spalte) = 1;
                app.anzWeiss = app.anzWeiss + 1
                app.menschFertig = 1
            end
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: SchwarzButton
        function SchwarzButtonPushed(app, event)
            % Position einlesen und schwarzen Stein setzen
            zeile = app.ZeileEditField.Value;
            spalte = app.SpalteEditField.Value;
            if(checkSetzen(app, zeile, spalte))
                app.spielfeld(zeile, spalte) = 2;
                app.anzSchwarz = app.anzSchwarz + 1;
            end
            printSpielfeld(app, app.spielfeld);
        end

        % Value changed function: SPIELButton
        function SPIELButtonValueChanged(app, event)
            value = app.SPIELButton.Value;
            if(value == 1)
                % Nochmal initialisieren, falls vorher Reset ausgeführt
                % wurde.
                app.spielfeld = [0 3 3 0 3 3 0;
                                 3 0 3 0 3 0 3;
                                 3 3 0 0 0 3 3;
                                 0 0 0 4 0 0 0;
                                 3 3 0 0 0 3 3;
                                 3 0 3 0 3 0 3;
                                 0 3 3 0 3 3 0];
                
                app.SpielaktivLamp.Color = 'green';
                while(value)
                    
                    %
                    % Compter spielt bzw. beginnt das Spiel.
                    %
                    computerZug(app, app.spielfeld);
                    %
                    %
                    %
                    % Checken, ob der Computer gewonnen hat.
                    app.anzWeiss
                    app.anzSchwarz
                    app.anzStartWeiss
                    if((app.anzSchwarz < 2) && (app.anzStartWeiss == 3)) || ((app.anzSchwarz <= 3) && (app.anzStartWeiss > 4))
                        app.gewinner = 1;
                        app.GewinnerLamp.Color = 'white'
                        s = 'Spiel ist zu Ende, Schwarz hat nur noch 3 Steine oder weniger.'
                        s = 'Weiss (Computer) hat gewonnen.'
                        break;
                    end

                    %
                    % Mensch spielt.
                    %
                    menschZug(app, app.spielfeld);
                    %
                    %
                    %
                    
                    % Checken, ob der Mensch gewonnen hat.
                    app.anzWeiss
                    app.anzSchwarz
                    app.anzStartWeiss
                    if((app.anzWeiss < 3) && (app.anzStartWeiss == 3)) || ((app.anzWeiss <= 3) && (app.anzStartWeiss > 3))
                        app.gewinner = 2;
                        app.GewinnerLamp.Color = 'black'
                        s = 'Spiel ist zu Ende, Weiss hat nur noch 3 Steine oder weniger.'
                        s = 'Schwarz (Mensch) hat gewonnen.'
                        break;
                    end      
                end 
                
                app.SpielaktivLamp.Color = [0.90,0.90,0.90];
                app.ComputerLamp.Enable = 'off';
                app.MenschLamp.Enable = 'off';
            else
                app.menschFertig = 1;
                app.ComputerLamp.Enable = 'off';
                app.MenschLamp.Enable = 'off';
            end
        end

        % Button pushed function: EinlesenButton
        function EinlesenButtonPushed(app, event)
%            app.spielfeld = einlesen(app.brickObj);
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: ConnectEV3Button
        function ConnectEV3ButtonPushed(app, event)
%            app.brickObj.connect('usb');
        end

        % Button pushed function: DisconnectEV3Button
        function DisconnectEV3ButtonPushed(app, event)
%            app.brickObj.disconnect();
        end

        % Button pushed function: lschenButton
        function lschenButtonPushed(app, event)
            % Position einlesen und weißen Stein setzen
            zeile = app.ZeileEditField.Value;
            spalte = app.SpalteEditField.Value;
                if(app.spielfeld(zeile, spalte) == 1)
                    app.anzWeiss = app.anzWeiss - 1;
                elseif(app.spielfeld(zeile, spalte) == 2)
                    app.anzSchwarz = app.anzSchwarz - 1;
                end
                app.spielfeld(zeile, spalte) = 0;
                app.menschFertig = 1;
            printSpielfeld(app, app.spielfeld);
        end

        % Button pushed function: setzenButton
        function setzenButtonPushed(app, event)
              zeile = app.ZeileEditField.Value;
              spalte = app.SpalteEditField.Value;
              % setzt nur schwarze Steine
              app.spielfeld(zeile, spalte) = 2;
              printSpielfeld(app, app.spielfeld);
              
              % Stein Setzen
%             zeile = app.ZeileEditField.Value;
%             spalte = app.SpalteEditField.Value;
%             aufheben(app.brickObj);
%             fahreZu(app.brickObj, zeile, spalte, 1);
%             ablegen(app.brickObj);
%             app.spielfeld(zeile, spalte) = 1;
%             printSpielfeld(app, app.spielfeld);
%             fahreZu(app.brickObj, zeile, spalte, -1);
%             armRunter(app.brickObj);
        end

        % Button pushed function: nehmenButton
        function nehmenButtonPushed(app, event)
              zeile = app.ZeileEditField.Value;
              spalte = app.SpalteEditField.Value;
              app.spielfeld(zeile, spalte) = 0;
              printSpielfeld(app, app.spielfeld);
            
%             zeile = app.ZeileEditField.Value;
%             spalte = app.SpalteEditField.Value;
%             armHoch(app.brickObj);
%             fahreZu(app.brickObj, zeile, spalte, 1)
%             armRunter(app.brickObj);
%             aufheben(app.brickObj);
%             app.spielfeld(zeile, spalte) = 0;
%             printSpielfeld(app, app.spielfeld);
%             fahreZu(app.brickObj, zeile, spalte, -1);
%             ablegen(app.brickObj);
%             armRunter(app.brickObj);
        end

        % Button pushed function: MenschfertigButton
        function MenschfertigButtonPushed(app, event)
            app.menschFertig = 1;
        end

        % Button pushed function: ComputerfertigButton
        function ComputerfertigButtonPushed(app, event)
            app.computerFertig = 1;
        end

        % Button pushed function: KorrekturenAbgeschlossenButton
        function KorrekturenAbgeschlossenButtonPushed(app, event)
            app.korrekturenAbgeschlossen = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '___________________________________';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '|';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '|';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '|';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '|';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '_____________________';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '___________________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '_____________________';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '___________________';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '___________________________________';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '___________________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '______________________________________________________';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '______________________________________________________';
            app.Lamp_3_3 = uilamp(app.UIFigure);
            app.Lamp_3_4 = uilamp(app.UIFigure);
            app.Lamp_3_5 = uilamp(app.UIFigure);
            app.Lamp_5_3 = uilamp(app.UIFigure);
            app.Lamp_5_4 = uilamp(app.UIFigure);
            app.Lamp_4_3 = uilamp(app.UIFigure);
            app.Lamp_5_5 = uilamp(app.UIFigure);
            app.Lamp_4_5 = uilamp(app.UIFigure);
            app.Lamp_4_2 = uilamp(app.UIFigure);
            app.Lamp_4_1 = uilamp(app.UIFigure);
            app.Lamp_6_2 = uilamp(app.UIFigure);
            app.Lamp_6_4 = uilamp(app.UIFigure);
            app.Lamp_2_2 = uilamp(app.UIFigure);
            app.Lamp_2_4 = uilamp(app.UIFigure);
            app.Lamp_2_6 = uilamp(app.UIFigure);
            app.Lamp_4_6 = uilamp(app.UIFigure);
            app.Lamp_6_6 = uilamp(app.UIFigure);
            app.Lamp_7_1 = uilamp(app.UIFigure);
            app.Lamp_1_1 = uilamp(app.UIFigure);
            app.Lamp_1_4 = uilamp(app.UIFigure);
            app.Lamp_1_7 = uilamp(app.UIFigure);
            app.Lamp_4_7 = uilamp(app.UIFigure);
            app.Lamp_7_7 = uilamp(app.UIFigure);
            app.Lamp_7_4 = uilamp(app.UIFigure);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '1                  2                3                4              5              6                 7';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '1';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '2';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '3';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '4';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '5';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '6';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '7';
            app.RandomButton = uibutton(app.UIFigure, 'state');
            app.RandomButton.ValueChangedFcn = createCallbackFcn(app, @RandomButtonValueChanged, true);
            app.RandomButton.Text = 'Random';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ZeileEditFieldLabel = uilabel(app.UIFigure);
            app.ZeileEditFieldLabel.Text = 'Zeile';
            app.ZeileEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpalteEditFieldLabel = uilabel(app.UIFigure);
            app.SpalteEditFieldLabel.Text = 'Spalte';
            app.SpalteEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeissButton = uibutton(app.UIFigure, 'push');
            app.WeissButton.ButtonPushedFcn = createCallbackFcn(app, @WeissButtonPushed, true);
            app.WeissButton.Text = 'Weiss';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.SchwarzButton = uibutton(app.UIFigure, 'push');
            app.SchwarzButton.ButtonPushedFcn = createCallbackFcn(app, @SchwarzButtonPushed, true);
            app.SchwarzButton.Text = 'Schwarz';
            app.SPIELButton = uibutton(app.UIFigure, 'state');
            app.SPIELButton.ValueChangedFcn = createCallbackFcn(app, @SPIELButtonValueChanged, true);
            app.SPIELButton.Text = 'SPIEL';
            app.MenschLampLabel = uilabel(app.UIFigure);
            app.MenschLampLabel.Text = 'Mensch';
            app.MenschLamp = uilamp(app.UIFigure);
            app.MenschLamp.Enable = 'off';
            app.ComputerLampLabel = uilabel(app.UIFigure);
            app.ComputerLampLabel.Text = 'Computer';
            app.ComputerLamp = uilamp(app.UIFigure);
            app.ComputerLamp.Enable = 'off';
            app.EinlesenButton = uibutton(app.UIFigure, 'push');
            app.EinlesenButton.ButtonPushedFcn = createCallbackFcn(app, @EinlesenButtonPushed, true);
            app.EinlesenButton.Text = 'Einlesen';
            app.ConnectEV3Button = uibutton(app.UIFigure, 'push');
            app.ConnectEV3Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectEV3ButtonPushed, true);
            app.ConnectEV3Button.Text = 'Connect EV3';
            app.DisconnectEV3Button = uibutton(app.UIFigure, 'push');
            app.DisconnectEV3Button.ButtonPushedFcn = createCallbackFcn(app, @DisconnectEV3ButtonPushed, true);
            app.DisconnectEV3Button.Text = 'Disconnect EV3';
            app.lschenButton = uibutton(app.UIFigure, 'push');
            app.lschenButton.ButtonPushedFcn = createCallbackFcn(app, @lschenButtonPushed, true);
            app.lschenButton.Text = 'löschen';
            app.setzenButton = uibutton(app.UIFigure, 'push');
            app.setzenButton.ButtonPushedFcn = createCallbackFcn(app, @setzenButtonPushed, true);
            app.setzenButton.Text = 'setzen';
            app.nehmenButton = uibutton(app.UIFigure, 'push');
            app.nehmenButton.ButtonPushedFcn = createCallbackFcn(app, @nehmenButtonPushed, true);
            app.nehmenButton.Text = 'nehmen';
            app.MenschfertigButton = uibutton(app.UIFigure, 'push');
            app.MenschfertigButton.ButtonPushedFcn = createCallbackFcn(app, @MenschfertigButtonPushed, true);
            app.MenschfertigButton.Text = 'Mensch fertig';
            app.ComputerfertigButton = uibutton(app.UIFigure, 'push');
            app.ComputerfertigButton.ButtonPushedFcn = createCallbackFcn(app, @ComputerfertigButtonPushed, true);
            app.ComputerfertigButton.Text = 'Computer fertig';
            app.KorrekturenAbgeschlossenButton = uibutton(app.UIFigure, 'push');
            app.KorrekturenAbgeschlossenButton.ButtonPushedFcn = createCallbackFcn(app, @KorrekturenAbgeschlossenButtonPushed, true);
            app.KorrekturenAbgeschlossenButton.Text = 'Korrekturen Abgeschlossen';
            app.GewinnerLampLabel = uilabel(app.UIFigure);
            app.GewinnerLampLabel.Text = 'Gewinner';
            app.GewinnerLamp = uilamp(app.UIFigure);
            app.SpielaktivLampLabel = uilabel(app.UIFigure);
            app.SpielaktivLampLabel.Text = 'Spiel aktiv';
            app.SpielaktivLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = muehle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end